classdef Tigerv3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OversightTabGroup              matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        LoginButton                    matlab.ui.control.Button
        importMapButton                matlab.ui.control.Button
        FlagunflagallElectrodesCheckBox  matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_22       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_21       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_20       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_19       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_18       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_17       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_16       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_15       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_14       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_13       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_12       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_11       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_10       matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_9        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_8        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_7        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_6        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_5        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_4        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_3        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_2        matlab.ui.control.CheckBox
        FlagElectrodeCheckBox_1        matlab.ui.control.CheckBox
        FlaggedLabel                   matlab.ui.control.Label
        ComfortLabel                   matlab.ui.control.Label
        ThresholdLabel                 matlab.ui.control.Label
        Comfort_22                     matlab.ui.control.NumericEditField
        Comfort_21                     matlab.ui.control.NumericEditField
        Comfort_20                     matlab.ui.control.NumericEditField
        Comfort_19                     matlab.ui.control.NumericEditField
        Comfort_18                     matlab.ui.control.NumericEditField
        Comfort_17                     matlab.ui.control.NumericEditField
        Comfort_16                     matlab.ui.control.NumericEditField
        Comfort_15                     matlab.ui.control.NumericEditField
        Comfort_14                     matlab.ui.control.NumericEditField
        Comfort_13                     matlab.ui.control.NumericEditField
        Comfort_12                     matlab.ui.control.NumericEditField
        Comfort_11                     matlab.ui.control.NumericEditField
        Comfort_10                     matlab.ui.control.NumericEditField
        Comfort_9                      matlab.ui.control.NumericEditField
        Comfort_8                      matlab.ui.control.NumericEditField
        Comfort_7                      matlab.ui.control.NumericEditField
        Comfort_6                      matlab.ui.control.NumericEditField
        Comfort_5                      matlab.ui.control.NumericEditField
        Comfort_4                      matlab.ui.control.NumericEditField
        Comfort_3                      matlab.ui.control.NumericEditField
        Comfort_2                      matlab.ui.control.NumericEditField
        Comfort_1                      matlab.ui.control.NumericEditField
        Threshold_22                   matlab.ui.control.NumericEditField
        Threshold_21                   matlab.ui.control.NumericEditField
        Threshold_20                   matlab.ui.control.NumericEditField
        Threshold_19                   matlab.ui.control.NumericEditField
        Threshold_18                   matlab.ui.control.NumericEditField
        Threshold_17                   matlab.ui.control.NumericEditField
        Threshold_16                   matlab.ui.control.NumericEditField
        Threshold_15                   matlab.ui.control.NumericEditField
        Threshold_14                   matlab.ui.control.NumericEditField
        Threshold_13                   matlab.ui.control.NumericEditField
        Threshold_12                   matlab.ui.control.NumericEditField
        Threshold_11                   matlab.ui.control.NumericEditField
        Threshold_10                   matlab.ui.control.NumericEditField
        Threshold_9                    matlab.ui.control.NumericEditField
        Threshold_8                    matlab.ui.control.NumericEditField
        Threshold_7                    matlab.ui.control.NumericEditField
        Threshold_6                    matlab.ui.control.NumericEditField
        Threshold_5                    matlab.ui.control.NumericEditField
        Threshold_4                    matlab.ui.control.NumericEditField
        Threshold_3                    matlab.ui.control.NumericEditField
        Threshold_2                    matlab.ui.control.NumericEditField
        Threshold_1                    matlab.ui.control.NumericEditField
        ExperimentTabGroup             matlab.ui.container.TabGroup
        TestSequenceTab                matlab.ui.container.Tab
        AmplitudesButton               matlab.ui.control.Button
        RunTest                        matlab.ui.control.Button
        ElectrodesButton               matlab.ui.control.Button
        SequenceNameLabel              matlab.ui.control.Label
        ChooseSequenceFileButton       matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
        DiscrimationExpTab             matlab.ui.container.Tab
        AmplitudesButton_2             matlab.ui.control.Button
        ElectrodesButton_2             matlab.ui.control.Button
        RunsEditField_2                matlab.ui.control.EditField
        RunsEditField_2Label           matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        FolderNameLabel                matlab.ui.control.Label
        ChooseSequenceFolderButton_2   matlab.ui.control.Button
        RunDiscrimination              matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        RankingExpTab                  matlab.ui.container.Tab
        TokenOrderDropDown             matlab.ui.control.DropDown
        TokenOrderDropDownLabel        matlab.ui.control.Label
        TokenpatternDropDown           matlab.ui.control.DropDown
        TokenpatternDropDownLabel      matlab.ui.control.Label
        RandomTokenOrderCheckBox       matlab.ui.control.CheckBox
        PickfilesButton                matlab.ui.control.Button
        AmplitudesEditField            matlab.ui.control.EditField
        AmplitudesEditFieldLabel       matlab.ui.control.Label
        ElectrodesEditField            matlab.ui.control.EditField
        ElectrodesEditFieldLabel       matlab.ui.control.Label
        AmplitudesButton_3             matlab.ui.control.Button
        ElectrodesButton_3             matlab.ui.control.Button
        RunsEditField                  matlab.ui.control.EditField
        RunsEditFieldLabel             matlab.ui.control.Label
        UITable_2                      matlab.ui.control.Table
        FolderNameLabel_2              matlab.ui.control.Label
        ChooseSequenceFolderButton_3   matlab.ui.control.Button
        RunRanking                     matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        DirectoryEditField             matlab.ui.control.EditField
        DirectoryEditFieldLabel        matlab.ui.control.Label
        PatientIDEditField             matlab.ui.control.EditField
        PatientIDEditFieldLabel        matlab.ui.control.Label
        GenerateSeqTab                 matlab.ui.container.Tab
        ZoomedViewLabel                matlab.ui.control.Label
        BurstGapEditField              matlab.ui.control.NumericEditField
        BurstGapmsLabel                matlab.ui.control.Label
        burstsperSubsequence           matlab.ui.control.NumericEditField
        ThisStimuluscontainsLabel      matlab.ui.control.Label
        InterpulsegapmsEditField_A     matlab.ui.control.NumericEditField
        InterpulsegapmsEditFieldLabel  matlab.ui.control.Label
        PulsesperburstEditField_A      matlab.ui.control.NumericEditField
        PulsesperFrameLabel            matlab.ui.control.Label
        BurstperiodmsEditField_A       matlab.ui.control.NumericEditField
        BurstperiodppsLabel            matlab.ui.control.Label
        InputgapsButton                matlab.ui.control.Button
        varyinggapsCheckBox            matlab.ui.control.CheckBox
        BurstsLabel                    matlab.ui.control.Label
        FilenameEditField_A            matlab.ui.control.EditField
        SaveAsButton_A                 matlab.ui.control.Button
        CreateSubsequenceLabel         matlab.ui.control.Label
        UIAxes_A_only                  matlab.ui.control.UIAxes
        DataAnalysisTab                matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        DiscriminationPlotTab          matlab.ui.container.Tab
        RankingPlotTab                 matlab.ui.container.Tab
        needautomatedlognamingLabel    matlab.ui.control.Label
        nameofLogorderorsoLabel        matlab.ui.control.Label
        createPlotButton               matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        usuallydirectorylikenormallydisorrankinglogfolderLabel  matlab.ui.control.Label
        FileorFoldertouseEditField     matlab.ui.control.EditField
        FileorFoldertouseEditFieldLabel  matlab.ui.control.Label
        PatientNameEditField           matlab.ui.control.EditField
        PatientNameEditFieldLabel      matlab.ui.control.Label
        giveinputdataButton            matlab.ui.control.Button
        AppSettingsTab                 matlab.ui.container.Tab
        intersequencemsEditField       matlab.ui.control.NumericEditField
        GoLiveCheckBox                 matlab.ui.control.CheckBox
        intersequencemsEditFieldLabel  matlab.ui.control.Label
        StimdurationmsEditField_A      matlab.ui.control.NumericEditField
        StimdurationmsEditFieldLabel   matlab.ui.control.Label
        ClevelsPulsewidthLabel         matlab.ui.control.Label
        InterphasegapsEditField_A      matlab.ui.control.NumericEditField
        InterphasegapsEditFieldLabel   matlab.ui.control.Label
        StimphasesEditField_A          matlab.ui.control.NumericEditField
        StimphasesEditFieldLabel       matlab.ui.control.Label
        ModeDropDown                   matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        c_levels_pulse_width           matlab.ui.control.NumericEditField
        PitchRankingDecisionRepetitionEditField  matlab.ui.control.NumericEditField
        PitchRankingDecisionRepetitionEditFieldLabel  matlab.ui.control.Label
        PitchRankingExperimentLabel    matlab.ui.control.Label
        Decideafter2unanimousDecisionsCheckBox  matlab.ui.control.CheckBox
        rampingactiveCheckBox          matlab.ui.control.CheckBox
        CalculateasDynamicRangeofMapCheckBox  matlab.ui.control.CheckBox
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)

        frame_period %frame period harcoded to 64.8 - maybe not hardcoded

        ComfortValues double = zeros(1,22); % preallocate Comforts
        ThresholdValues double = zeros(1,22); % preallocate Threshold
        FlagElectrodeCheckBoxValues logical = false(1,22); % preallocate Flags

        PatientID
    end
    
    methods (Access = private)

        function MapValueChanged(app, event, valuesArrayName)
            src = event.Source;
            value = src.Value;
            name = src.Tag;   % e.g. "C7"
        
            idx = extractIndexFromTag(app, name);  
        
            if checkMapLevels(app, idx, name, value, valuesArrayName)
                src.Value = app.(valuesArrayName)(idx);
                return;
            end

            app.(valuesArrayName)(idx) = value;   % store value
        
            disp(valuesArrayName + " "  + idx + " geändert: " + value)
            disp(app.(valuesArrayName))
        end

        function idx = extractIndexFromTag(~, tag)
            % Extracts the numeric part of a tag string
            numStr = regexp(tag, '\d+', 'match');  % find all numbers
            idx = str2double(numStr{1});           % convert first match to number
        end

        function setupMapValues(app, prefix, valuesArrayName)
            for i = 1:22
                compName = prefix + "_" + i;
                app.(compName).Tag = prefix(1)+ "_" + i; % C or T or F
                app.(compName).ValueChangedFcn = @(src,event) MapValueChanged(app,event,valuesArrayName);
                app.(valuesArrayName)(i) = app.(compName).Value;
            end
        end

        function oppositeArrayName = getOppositeArray(~, valuesArrayName) % not used currently
            % Returns the opposite array name for sliders/values
            switch valuesArrayName
                case "ComfortValues"
                    oppositeArrayName = "ThresholdValues";
                case "ThresholdValues"
                    oppositeArrayName = "ComfortValues";
                otherwise
                    errordlg("No valuesArrayName") % or error/warning if needed
            end
        end

        function stopFlag = checkMapLevels(app, idx, name, value, valuesArrayName) % error if T is over C  
            stopFlag = false;

            switch valuesArrayName
                case "ThresholdValues"
                    if value > app.ComfortValues(idx) 
                        errordlg(name + " is over Comfort!")
                        stopFlag = true;
                    end
                case "ComfortValues"
                    if value < app.ThresholdValues(idx) 
                        errordlg(name + " is under Threshold!")
                        stopFlag = true;
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            setupMapValues(app, "Comfort", "ComfortValues");
            setupMapValues(app, "Threshold", "ThresholdValues");
            setupMapValues(app, "FlagElectrodeCheckBox", "FlagElectrodeCheckBoxValues")

            app.PatientID = app.PatientIDEditField.Value;
        end

        % Value changed function: PatientIDEditField
        function PatientIDEditFieldValueChanged(app, event)
            value = app.PatientIDEditField.Value;
            app.PatientID = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OversightTabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.OversightTabGroup);
            app.MainTab.Title = 'Main';
            app.PatientIDEditFieldLabel = uilabel(app.MainTab);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.MainTab, 'text');
            app.PatientIDEditField.ValueChangedFcn = createCallbackFcn(app, @PatientIDEditFieldValueChanged, true);
            app.DirectoryEditFieldLabel = uilabel(app.MainTab);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.MainTab, 'text');
            app.ExperimentTabGroup = uitabgroup(app.MainTab);
            app.TestSequenceTab = uitab(app.ExperimentTabGroup);
            app.TestSequenceTab.Title = 'Test Sequence';
            app.UIAxes_3 = uiaxes(app.TestSequenceTab);
            title(app.UIAxes_3, 'Sequence')
            xlabel(app.UIAxes_3, 'Time [ms]')
            ylabel(app.UIAxes_3, 'Amplitude [CL]')
            app.ChooseSequenceFileButton = uibutton(app.TestSequenceTab, 'push');
            app.ChooseSequenceFileButton.Text = 'Choose Sequence File';
            app.SequenceNameLabel = uilabel(app.TestSequenceTab);
            app.SequenceNameLabel.Text = 'Sequence Name ';
            app.ElectrodesButton = uibutton(app.TestSequenceTab, 'push');
            app.ElectrodesButton.Text = 'Electrodes';
            app.RunTest = uibutton(app.TestSequenceTab, 'push');
            app.RunTest.Text = 'Run';
            app.AmplitudesButton = uibutton(app.TestSequenceTab, 'push');
            app.AmplitudesButton.Text = 'Amplitudes';
            app.DiscrimationExpTab = uitab(app.ExperimentTabGroup);
            app.DiscrimationExpTab.Title = 'Discrimation Exp';
            app.UIAxes = uiaxes(app.DiscrimationExpTab);
            title(app.UIAxes, 'Sequence')
            xlabel(app.UIAxes, 'Time [ms]')
            ylabel(app.UIAxes, 'Amplitude [CL]')
            app.RunDiscrimination = uibutton(app.DiscrimationExpTab, 'push');
            app.RunDiscrimination.Text = 'Run';
            app.ChooseSequenceFolderButton_2 = uibutton(app.DiscrimationExpTab, 'push');
            app.ChooseSequenceFolderButton_2.Text = 'Choose Sequence Folder';
            app.FolderNameLabel = uilabel(app.DiscrimationExpTab);
            app.FolderNameLabel.Text = 'Folder Name ';
            app.UITable = uitable(app.DiscrimationExpTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.RunsEditField_2Label = uilabel(app.DiscrimationExpTab);
            app.RunsEditField_2Label.Text = 'Runs';
            app.RunsEditField_2 = uieditfield(app.DiscrimationExpTab, 'text');
            app.ElectrodesButton_2 = uibutton(app.DiscrimationExpTab, 'push');
            app.ElectrodesButton_2.Text = 'Electrodes';
            app.AmplitudesButton_2 = uibutton(app.DiscrimationExpTab, 'push');
            app.AmplitudesButton_2.Text = 'Amplitudes';
            app.RankingExpTab = uitab(app.ExperimentTabGroup);
            app.RankingExpTab.Title = 'Ranking Exp';
            app.UIAxes_2 = uiaxes(app.RankingExpTab);
            title(app.UIAxes_2, 'Sequence')
            xlabel(app.UIAxes_2, 'Time [ms]')
            ylabel(app.UIAxes_2, 'Amplitude [CL]')
            app.RunRanking = uibutton(app.RankingExpTab, 'push');
            app.RunRanking.Text = 'Run';
            app.ChooseSequenceFolderButton_3 = uibutton(app.RankingExpTab, 'push');
            app.ChooseSequenceFolderButton_3.Text = 'Choose Sequence Folder';
            app.FolderNameLabel_2 = uilabel(app.RankingExpTab);
            app.FolderNameLabel_2.Text = 'Folder Name ';
            app.UITable_2 = uitable(app.RankingExpTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.RunsEditFieldLabel = uilabel(app.RankingExpTab);
            app.RunsEditFieldLabel.Text = 'Runs';
            app.RunsEditField = uieditfield(app.RankingExpTab, 'text');
            app.ElectrodesButton_3 = uibutton(app.RankingExpTab, 'push');
            app.ElectrodesButton_3.Text = 'Electrodes';
            app.AmplitudesButton_3 = uibutton(app.RankingExpTab, 'push');
            app.AmplitudesButton_3.Text = 'Amplitudes';
            app.ElectrodesEditFieldLabel = uilabel(app.RankingExpTab);
            app.ElectrodesEditFieldLabel.Text = 'Electrodes';
            app.ElectrodesEditField = uieditfield(app.RankingExpTab, 'text');
            app.AmplitudesEditFieldLabel = uilabel(app.RankingExpTab);
            app.AmplitudesEditFieldLabel.Text = 'Amplitudes';
            app.AmplitudesEditField = uieditfield(app.RankingExpTab, 'text');
            app.PickfilesButton = uibutton(app.RankingExpTab, 'push');
            app.PickfilesButton.Text = 'Pick files';
            app.RandomTokenOrderCheckBox = uicheckbox(app.RankingExpTab);
            app.RandomTokenOrderCheckBox.Text = 'Random Token Order';
            app.TokenpatternDropDownLabel = uilabel(app.RankingExpTab);
            app.TokenpatternDropDownLabel.Text = 'Token pattern ';
            app.TokenpatternDropDown = uidropdown(app.RankingExpTab);
            app.TokenpatternDropDown.Items = {'Odd Token Out ', 'A - B ', '4I2AFC'};
            app.TokenpatternDropDown.Value = 'Odd Token Out ';
            app.TokenOrderDropDownLabel = uilabel(app.RankingExpTab);
            app.TokenOrderDropDownLabel.Text = 'Token Order';
            app.TokenOrderDropDown = uidropdown(app.RankingExpTab);
            app.TokenOrderDropDown.Items = {'A - B - A', 'B - A - A', 'A - A - B'};
            app.TokenOrderDropDown.Value = 'A - B - A';
            app.Threshold_1 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_1.Limits = [0 255];
            app.Threshold_2 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_2.Limits = [0 255];
            app.Threshold_3 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_3.Limits = [0 255];
            app.Threshold_4 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_4.Limits = [0 255];
            app.Threshold_5 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_5.Limits = [0 255];
            app.Threshold_6 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_6.Limits = [0 255];
            app.Threshold_7 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_7.Limits = [0 255];
            app.Threshold_8 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_8.Limits = [0 255];
            app.Threshold_9 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_9.Limits = [0 255];
            app.Threshold_10 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_10.Limits = [0 255];
            app.Threshold_11 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_11.Limits = [0 255];
            app.Threshold_12 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_12.Limits = [0 255];
            app.Threshold_13 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_13.Limits = [0 255];
            app.Threshold_14 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_14.Limits = [0 255];
            app.Threshold_15 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_15.Limits = [0 255];
            app.Threshold_16 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_16.Limits = [0 255];
            app.Threshold_17 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_17.Limits = [0 255];
            app.Threshold_18 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_18.Limits = [0 255];
            app.Threshold_19 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_19.Limits = [0 255];
            app.Threshold_20 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_20.Limits = [0 255];
            app.Threshold_21 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_21.Limits = [0 255];
            app.Threshold_22 = uieditfield(app.MainTab, 'numeric');
            app.Threshold_22.Limits = [0 255];
            app.Comfort_1 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_1.Limits = [0 255];
            app.Comfort_1.Value = 255;
            app.Comfort_2 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_2.Limits = [0 255];
            app.Comfort_2.Value = 255;
            app.Comfort_3 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_3.Limits = [0 255];
            app.Comfort_3.Value = 255;
            app.Comfort_4 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_4.Limits = [0 255];
            app.Comfort_4.Value = 255;
            app.Comfort_5 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_5.Limits = [0 255];
            app.Comfort_5.Value = 255;
            app.Comfort_6 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_6.Limits = [0 255];
            app.Comfort_6.Value = 255;
            app.Comfort_7 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_7.Limits = [0 255];
            app.Comfort_7.Value = 255;
            app.Comfort_8 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_8.Limits = [0 255];
            app.Comfort_8.Value = 255;
            app.Comfort_9 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_9.Limits = [0 255];
            app.Comfort_9.Value = 255;
            app.Comfort_10 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_10.Limits = [0 255];
            app.Comfort_10.Value = 255;
            app.Comfort_11 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_11.Limits = [0 255];
            app.Comfort_11.Value = 255;
            app.Comfort_12 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_12.Limits = [0 255];
            app.Comfort_12.Value = 255;
            app.Comfort_13 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_13.Limits = [0 255];
            app.Comfort_13.Value = 255;
            app.Comfort_14 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_14.Limits = [0 255];
            app.Comfort_14.Value = 255;
            app.Comfort_15 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_15.Limits = [0 255];
            app.Comfort_15.Value = 255;
            app.Comfort_16 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_16.Limits = [0 255];
            app.Comfort_16.Value = 255;
            app.Comfort_17 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_17.Limits = [0 255];
            app.Comfort_17.Value = 255;
            app.Comfort_18 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_18.Limits = [0 255];
            app.Comfort_18.Value = 255;
            app.Comfort_19 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_19.Limits = [0 255];
            app.Comfort_19.Value = 255;
            app.Comfort_20 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_20.Limits = [0 255];
            app.Comfort_20.Value = 255;
            app.Comfort_21 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_21.Limits = [0 255];
            app.Comfort_21.Value = 255;
            app.Comfort_22 = uieditfield(app.MainTab, 'numeric');
            app.Comfort_22.Limits = [0 255];
            app.Comfort_22.Value = 255;
            app.ThresholdLabel = uilabel(app.MainTab);
            app.ThresholdLabel.Text = 'Threshold';
            app.ComfortLabel = uilabel(app.MainTab);
            app.ComfortLabel.Text = 'Comfort';
            app.FlaggedLabel = uilabel(app.MainTab);
            app.FlaggedLabel.Text = 'Flagged';
            app.FlagElectrodeCheckBox_1 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_1.Text = '1';
            app.FlagElectrodeCheckBox_2 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_2.Text = '2';
            app.FlagElectrodeCheckBox_3 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_3.Text = '3';
            app.FlagElectrodeCheckBox_4 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_4.Text = '4';
            app.FlagElectrodeCheckBox_5 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_5.Text = '5';
            app.FlagElectrodeCheckBox_6 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_6.Text = '6';
            app.FlagElectrodeCheckBox_7 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_7.Text = '7';
            app.FlagElectrodeCheckBox_8 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_8.Text = '8';
            app.FlagElectrodeCheckBox_9 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_9.Text = '9';
            app.FlagElectrodeCheckBox_10 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_10.Text = '10';
            app.FlagElectrodeCheckBox_11 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_11.Text = '11';
            app.FlagElectrodeCheckBox_12 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_12.Text = '12';
            app.FlagElectrodeCheckBox_13 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_13.Text = '13';
            app.FlagElectrodeCheckBox_14 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_14.Text = '14';
            app.FlagElectrodeCheckBox_15 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_15.Text = '15';
            app.FlagElectrodeCheckBox_16 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_16.Text = '16';
            app.FlagElectrodeCheckBox_17 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_17.Text = '17';
            app.FlagElectrodeCheckBox_18 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_18.Text = '18';
            app.FlagElectrodeCheckBox_19 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_19.Text = '19';
            app.FlagElectrodeCheckBox_20 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_20.Text = '20';
            app.FlagElectrodeCheckBox_21 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_21.Text = '21';
            app.FlagElectrodeCheckBox_22 = uicheckbox(app.MainTab);
            app.FlagElectrodeCheckBox_22.Text = '22';
            app.FlagunflagallElectrodesCheckBox = uicheckbox(app.MainTab);
            app.FlagunflagallElectrodesCheckBox.Text = 'Flag/unflag all Electrodes';
            app.importMapButton = uibutton(app.MainTab, 'push');
            app.importMapButton.Text = 'import Map';
            app.LoginButton = uibutton(app.MainTab, 'push');
            app.LoginButton.Text = 'Log in ';
            app.GenerateSeqTab = uitab(app.OversightTabGroup);
            app.GenerateSeqTab.Title = 'Generate Seq';
            app.UIAxes_A_only = uiaxes(app.GenerateSeqTab);
            title(app.UIAxes_A_only, 'Zoomed view')
            xlabel(app.UIAxes_A_only, 'Time [ms]')
            ylabel(app.UIAxes_A_only, 'Amplitude [CL]')
            app.CreateSubsequenceLabel = uilabel(app.GenerateSeqTab);
            app.CreateSubsequenceLabel.Text = 'Create Subsequence';
            app.SaveAsButton_A = uibutton(app.GenerateSeqTab, 'push');
            app.SaveAsButton_A.Tooltip = {'Click "Graph" before saving to generate the correct .mat files'};
            app.SaveAsButton_A.Text = 'Save As:';
            app.FilenameEditField_A = uieditfield(app.GenerateSeqTab, 'text');
            app.FilenameEditField_A.Value = 'Insert_Filename';
            app.BurstsLabel = uilabel(app.GenerateSeqTab);
            app.BurstsLabel.Text = 'Bursts';
            app.varyinggapsCheckBox = uicheckbox(app.GenerateSeqTab);
            app.varyinggapsCheckBox.Text = 'varying gaps';
            app.InputgapsButton = uibutton(app.GenerateSeqTab, 'push');
            app.InputgapsButton.Text = 'Input gaps';
            app.BurstperiodppsLabel = uilabel(app.GenerateSeqTab);
            app.BurstperiodppsLabel.Text = 'Frame-Rate [pps]';
            app.BurstperiodmsEditField_A = uieditfield(app.GenerateSeqTab, 'numeric');
            app.BurstperiodmsEditField_A.Limits = [0 Inf];
            app.BurstperiodmsEditField_A.ValueDisplayFormat = '%.4f';
            app.BurstperiodmsEditField_A.Tooltip = {'Enter burst period in ms'};
            app.BurstperiodmsEditField_A.Value = 900;
            app.PulsesperFrameLabel = uilabel(app.GenerateSeqTab);
            app.PulsesperFrameLabel.Text = 'Pulses per Frame';
            app.PulsesperburstEditField_A = uieditfield(app.GenerateSeqTab, 'numeric');
            app.PulsesperburstEditField_A.Limits = [1 Inf];
            app.PulsesperburstEditField_A.RoundFractionalValues = 'on';
            app.PulsesperburstEditField_A.ValueDisplayFormat = '%.0f';
            app.PulsesperburstEditField_A.Tooltip = {'Enter number of pulses within a burst. '; 'NOTE: If only 1 pulse is entered, interpulsegap will be ignored'};
            app.PulsesperburstEditField_A.Value = 1;
            app.InterpulsegapmsEditFieldLabel = uilabel(app.GenerateSeqTab);
            app.InterpulsegapmsEditFieldLabel.Text = 'Inter pulse gap [ms]';
            app.InterpulsegapmsEditField_A = uieditfield(app.GenerateSeqTab, 'numeric');
            app.InterpulsegapmsEditField_A.Limits = [0 Inf];
            app.InterpulsegapmsEditField_A.ValueDisplayFormat = '%.4f';
            app.InterpulsegapmsEditField_A.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.InterpulsegapmsEditField_A.Value = 0.1296;
            app.ThisStimuluscontainsLabel = uilabel(app.GenerateSeqTab);
            app.ThisStimuluscontainsLabel.Text = 'This Stimulus contains';
            app.burstsperSubsequence = uieditfield(app.GenerateSeqTab, 'numeric');
            app.burstsperSubsequence.Limits = [0 Inf];
            app.burstsperSubsequence.ValueDisplayFormat = '%.0f';
            app.burstsperSubsequence.Editable = 'off';
            app.burstsperSubsequence.Tooltip = {'Enter interpulse gap within a burst in ms'};
            app.BurstGapmsLabel = uilabel(app.GenerateSeqTab);
            app.BurstGapmsLabel.Text = 'Wait-Period [ms]';
            app.BurstGapEditField = uieditfield(app.GenerateSeqTab, 'numeric');
            app.BurstGapEditField.Limits = [0 Inf];
            app.BurstGapEditField.ValueDisplayFormat = '%.4f';
            app.BurstGapEditField.Tooltip = {'Enter burst period in ms'};
            app.BurstGapEditField.Value = 0.5927;
            app.ZoomedViewLabel = uilabel(app.GenerateSeqTab);
            app.ZoomedViewLabel.Text = 'Zoomed View';
            app.DataAnalysisTab = uitab(app.OversightTabGroup);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.giveinputdataButton = uibutton(app.DataAnalysisTab, 'push');
            app.giveinputdataButton.Text = 'give input data';
            app.PatientNameEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.PatientNameEditFieldLabel.Text = 'Patient Name';
            app.PatientNameEditField = uieditfield(app.DataAnalysisTab, 'text');
            app.FileorFoldertouseEditFieldLabel = uilabel(app.DataAnalysisTab);
            app.FileorFoldertouseEditFieldLabel.Text = 'File or Folder to use';
            app.FileorFoldertouseEditField = uieditfield(app.DataAnalysisTab, 'text');
            app.usuallydirectorylikenormallydisorrankinglogfolderLabel = uilabel(app.DataAnalysisTab);
            app.usuallydirectorylikenormallydisorrankinglogfolderLabel.Text = 'usually directory like normally + dis or ranking log folder ';
            app.TabGroup = uitabgroup(app.DataAnalysisTab);
            app.DiscriminationPlotTab = uitab(app.TabGroup);
            app.DiscriminationPlotTab.Title = 'Discrimination Plot ';
            app.RankingPlotTab = uitab(app.TabGroup);
            app.RankingPlotTab.Title = 'Ranking Plot';
            app.UIAxes2 = uiaxes(app.RankingPlotTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.createPlotButton = uibutton(app.RankingPlotTab, 'push');
            app.createPlotButton.Text = 'create Plot';
            app.nameofLogorderorsoLabel = uilabel(app.RankingPlotTab);
            app.nameofLogorderorsoLabel.Text = 'name of Log order or so ';
            app.needautomatedlognamingLabel = uilabel(app.RankingPlotTab);
            app.needautomatedlognamingLabel.Text = 'need automated log naming ';
            app.AppSettingsTab = uitab(app.OversightTabGroup);
            app.AppSettingsTab.Title = 'App Settings';
            app.CalculateasDynamicRangeofMapCheckBox = uicheckbox(app.AppSettingsTab);
            app.CalculateasDynamicRangeofMapCheckBox.Text = 'Calculate as Dynamic Range of Map';
            app.rampingactiveCheckBox = uicheckbox(app.AppSettingsTab);
            app.rampingactiveCheckBox.Text = 'ramping active';
            app.Decideafter2unanimousDecisionsCheckBox = uicheckbox(app.AppSettingsTab);
            app.Decideafter2unanimousDecisionsCheckBox.Text = 'Decide after 2 unanimous Decisions';
            app.Decideafter2unanimousDecisionsCheckBox.Value = true;
            app.PitchRankingExperimentLabel = uilabel(app.AppSettingsTab);
            app.PitchRankingExperimentLabel.Text = 'Pitch Ranking Experiment:';
            app.PitchRankingDecisionRepetitionEditFieldLabel = uilabel(app.AppSettingsTab);
            app.PitchRankingDecisionRepetitionEditFieldLabel.Text = 'Pitch Ranking Decision Repetition ';
            app.PitchRankingDecisionRepetitionEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.PitchRankingDecisionRepetitionEditField.Limits = [0 Inf];
            app.PitchRankingDecisionRepetitionEditField.RoundFractionalValues = 'on';
            app.PitchRankingDecisionRepetitionEditField.Value = 6;
            app.c_levels_pulse_width = uieditfield(app.AppSettingsTab, 'numeric');
            app.c_levels_pulse_width.Limits = [0 Inf];
            app.c_levels_pulse_width.ValueDisplayFormat = '%.1f';
            app.c_levels_pulse_width.Tooltip = {'Enter pulse width'};
            app.c_levels_pulse_width.Value = 50;
            app.ModeDropDownLabel = uilabel(app.AppSettingsTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.AppSettingsTab);
            app.ModeDropDown.Items = {'MP1', 'MP2', 'MP1+2'};
            app.ModeDropDown.Value = 'MP1+2';
            app.StimphasesEditFieldLabel = uilabel(app.AppSettingsTab);
            app.StimphasesEditFieldLabel.Text = 'Stim phase [µs]';
            app.StimphasesEditField_A = uieditfield(app.AppSettingsTab, 'numeric');
            app.StimphasesEditField_A.Limits = [0 Inf];
            app.StimphasesEditField_A.ValueDisplayFormat = '%.0f';
            app.StimphasesEditField_A.Tooltip = {'Enter phase width in µs'};
            app.StimphasesEditField_A.Value = 25;
            app.InterphasegapsEditFieldLabel = uilabel(app.AppSettingsTab);
            app.InterphasegapsEditFieldLabel.Text = 'Interphase gap [µs]';
            app.InterphasegapsEditField_A = uieditfield(app.AppSettingsTab, 'numeric');
            app.InterphasegapsEditField_A.Limits = [0 Inf];
            app.InterphasegapsEditField_A.ValueDisplayFormat = '%.0f';
            app.InterphasegapsEditField_A.Tooltip = {'Enter interphase gap in µs'};
            app.InterphasegapsEditField_A.Value = 7;
            app.ClevelsPulsewidthLabel = uilabel(app.AppSettingsTab);
            app.ClevelsPulsewidthLabel.Text = 'C levels Pulse width';
            app.StimdurationmsEditFieldLabel = uilabel(app.AppSettingsTab);
            app.StimdurationmsEditFieldLabel.Text = 'Stim duration [ms]';
            app.StimdurationmsEditField_A = uieditfield(app.AppSettingsTab, 'numeric');
            app.StimdurationmsEditField_A.Limits = [0 Inf];
            app.StimdurationmsEditField_A.Tooltip = {'Enter stim duration in ms'};
            app.StimdurationmsEditField_A.Value = 500;
            app.intersequencemsEditFieldLabel = uilabel(app.AppSettingsTab);
            app.intersequencemsEditFieldLabel.Text = 'intersequence [ms]';
            app.GoLiveCheckBox = uicheckbox(app.AppSettingsTab);
            app.GoLiveCheckBox.Text = 'Go Live';
            app.GoLiveCheckBox.Value = true;
            app.intersequencemsEditField = uieditfield(app.AppSettingsTab, 'numeric');
            app.intersequencemsEditField.Value = 500;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tigerv3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end